classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Tugas1Citra                   matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        tp_y                          matlab.ui.control.NumericEditField
        yEditFieldLabel               matlab.ui.control.Label
        tp_c                          matlab.ui.control.NumericEditField
        cEditField_2Label             matlab.ui.control.Label
        tl_c                          matlab.ui.control.NumericEditField
        cEditFieldLabel               matlab.ui.control.Label
        ib_b                          matlab.ui.control.NumericEditField
        bEditFieldLabel               matlab.ui.control.Label
        ib_a                          matlab.ui.control.NumericEditField
        aEditFieldLabel               matlab.ui.control.Label
        OperasiLabel                  matlab.ui.control.Label
        CariButton                    matlab.ui.control.Button
        HitungHistogramButton         matlab.ui.control.Button
        HistogramSpecificationButton  matlab.ui.control.Button
        HistogramEqualizationButton   matlab.ui.control.Button
        PereganganHistogramButton     matlab.ui.control.Button
        TransformasiPangkatButton     matlab.ui.control.Button
        TransformasiLogButton         matlab.ui.control.Button
        ImageBrighteningButton        matlab.ui.control.Button
        PilihCitraLabel               matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        PreviewCitraLabel             matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Properties for functions
    
    properties (Access = public)
        CurrentImage = ""
    end
    
    methods (Access = private)
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Tugas1Citra.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {400, 400};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {282, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: HitungHistogramButton
        function HitungHistogramButtonPushed(app, event)
            if(app.CurrentImage == "")
                warndlg('Belum Ada Citra Terpilih','Gagal');
                return
            end
            res = app.CurrentImage
            [rows, columns, ~] = size(res);
            counts = zeros(1, 256);
            for col = 1 : columns
            	for row = 1 : rows
            		grayLevel = res(row, col);
                    % Menghitung grayLevel dari setiap pixel
            		counts(grayLevel+ 1) = counts(grayLevel+1) + 1;
            	end
            end
            % Menampilkan histogram dalam bentuk plot
            grayLevels = 0 : 255;
            bar(grayLevels, counts, 'BarWidth', 1, 'FaceColor', 'b');
            xlabel('Gray Level', 'FontSize', 20);
            ylabel('Pixel Count', 'FontSize', 20);
            title('Histogram', 'FontSize', 20);
            grid on;
        end

        % Button pushed function: CariButton
        function CariButtonPushed(app, event)
            app.CurrentImage = imgetfile
            app.Image.ImageSource=app.CurrentImage;
        end

        % Button pushed function: ImageBrighteningButton
        function ImageBrighteningButtonPushed(app, event)
            if(app.CurrentImage == "")
                warndlg('Belum Ada Citra Terpilih','Gagal');
                return
            end
            res = imread(app.CurrentImage)
            res = app.ib_a.Value * res + app.ib_b.Value
            imshow(res)
        end

        % Button pushed function: TransformasiLogButton
        function TransformasiLogButtonPushed(app, event)
            if(app.CurrentImage == "")
                warndlg('Belum Ada Citra Terpilih','Gagal');
                return
            end
            res = imread(app.CurrentImage)
            res = app.tl_c.Value * log(im2double(res)+1)
            imshow(res)
        end

        % Button pushed function: TransformasiPangkatButton
        function TransformasiPangkatButtonPushed(app, event)
            if(app.CurrentImage == "")
                warndlg('Belum Ada Citra Terpilih','Gagal');
                return
            end
            res = imread(app.CurrentImage)
            res = app.tp_c.Value * res.^app.tp_y.Value
            imshow(res)
        end

        % Button pushed function: PereganganHistogramButton
        function PereganganHistogramButtonPushed(app, event)
            if(app.CurrentImage == "")
                warndlg('Belum Ada Citra Terpilih','Gagal');
                return
            end
            res2 = imread(app.CurrentImage)
            res = app.CurrentImage
            [rows, columns, ~] = size(res);
            counts = zeros(1, 256);
            for col = 1 : columns
            	for row = 1 : rows
            		grayLevel = res(row, col);
                    % Menghitung grayLevel dari setiap pixel
            		counts(grayLevel+ 1) = counts(grayLevel+1) + 1;
            	end
            end
            % Menampilkan histogram dalam bentuk plot
            subplot(2,2,1)
            imshow(res2)
            subplot(2,2,2)
            grayLevels = 0 : 255;
            bar(grayLevels, counts, 'BarWidth', 1, 'FaceColor', 'b');
            xlabel('Gray Level', 'FontSize', 12);
            ylabel('Pixel Count', 'FontSize', 20);
            title('Gambar Semula', 'FontSize', 12);
            grid on;
            subplot(2,2,3)
            new_res = imadjust(res2,stretchlim(res2),[]);
            imshow(new_res)
            subplot(2,2,4)
            res = new_res
            [rows, columns, ~] = size(res);
            counts = zeros(1, 256);
            for col = 1 : columns
            	for row = 1 : rows
            		grayLevel = res(row, col);
                    % Menghitung grayLevel dari setiap pixel
            		counts(grayLevel+ 1) = counts(grayLevel+1) + 1;
            	end
            end
            % Menampilkan histogram dalam bentuk plot
            grayLevels = 0 : 255;
            bar(grayLevels, counts, 'BarWidth', 1, 'FaceColor', 'b');
            xlabel('Gray Level', 'FontSize', 12);
            ylabel('Pixel Count', 'FontSize', 20);
            title('Setelah Peregangan Kontras', 'FontSize', 12);
            grid on;
        end

        % Button pushed function: HistogramEqualizationButton
        function HistogramEqualizationButtonPushed(app, event)
            if(app.CurrentImage == "")
                warndlg('Belum Ada Citra Terpilih','Gagal');
                return
            end
            ori = double(imread(app.CurrentImage));
            numofpixels=size(ori,1)*size(ori,2);
            res=uint8(zeros(size(ori,1),size(ori,2)));
            freq=zeros(256,1);
            probf=zeros(256,1);
            probc=zeros(256,1);
            cum=zeros(256,1);
            output=zeros(256,1);
            for i=1:size(ori,1)
                for j=1:size(ori,2)
                    value=ori(i,j);
                    freq(value+1)=freq(value+1)+1;
                    probf(value+1)=freq(value+1)/numofpixels;
                end
            end
            sum=0;
            no_bins=255;
            for i=1:size(probf)
               sum=sum+freq(i);
               cum(i)=sum;
               probc(i)=cum(i)/numofpixels;
               output(i)=round(probc(i)*no_bins);
            end
            for i=1:size(ori,1)
                for j=1:size(ori,2)
                        res(i,j)=output(ori(i,j)+1);
                end
            end
            subplot(2,2,1);
            imshow(uint8(ori));
            subplot(2,2,2);
            [rows, columns, ~] = size(ori);
            counts = zeros(1, 256);
            for col = 1 : columns
            	for row = 1 : rows
            		grayLevel = ori(row, col);
                    % Menghitung grayLevel dari setiap pixel
            		counts(grayLevel+ 1) = counts(grayLevel+1) + 1;
            	end
            end
            % Menampilkan histogram dalam bentuk plot
            grayLevels = 0 : 255;
            bar(grayLevels, counts, 'BarWidth', 1, 'FaceColor', 'b');
            xlabel('Gray Level', 'FontSize', 12);
            ylabel('Pixel Count', 'FontSize', 20);
            title('Gambar Semula', 'FontSize', 12);
            grid on;
            subplot(2,2,3);
            imshow(uint8(res));
            subplot(2,2,4);
            [rows, columns, ~] = size(res);
            counts = zeros(1, 256);
            for col = 1 : columns
            	for row = 1 : rows
            		grayLevel = res(row, col);
                    % Menghitung grayLevel dari setiap pixel
            		counts(grayLevel+ 1) = counts(grayLevel+1) + 1;
            	end
            end
            % Menampilkan histogram dalam bentuk plot
            grayLevels = 0 : 255;
            bar(grayLevels, counts, 'BarWidth', 1, 'FaceColor', 'b');
            xlabel('Gray Level', 'FontSize', 12);
            ylabel('Pixel Count', 'FontSize', 20);
            title('Setelah Equalization', 'FontSize', 12);
            grid on;
        end

        % Button pushed function: HistogramSpecificationButton
        function HistogramSpecificationButtonPushed(app, event)
            if(app.CurrentImage == "")
                warndlg('Belum Ada Citra Terpilih','Gagal');
                return
            end
            ref = imread(imgetfile)
            ori = imread(app.CurrentImage)
            subplot(3,2,1)
            imshow(ori)
            subplot(3,2,2)
            [rows, columns, ~] = size(ori);
            ori_counts = zeros(1, 256);
            for col = 1 : columns
            	for row = 1 : rows
            		grayLevel = ori(row, col);
                    % Menghitung grayLevel dari setiap pixel
            		ori_counts(grayLevel+ 1) = ori_counts(grayLevel+1) + 1;
            	end
            end
            % Menampilkan histogram dalam bentuk plot
            grayLevels = 0 : 255;
            bar(grayLevels, ori_counts, 'BarWidth', 1, 'FaceColor', 'b');
            xlabel('Gray Level', 'FontSize', 12);
            ylabel('Pixel Count', 'FontSize', 20);
            title('Gambar Semula', 'FontSize', 12);
            grid on;
            subplot(3,2,3)
            imshow(ref)
            subplot(3,2,4)
            [rows, columns, ~] = size(ref);
            ref_counts = zeros(1, 256);
            for col = 1 : columns
            	for row = 1 : rows
            		grayLevel = ref(row, col);
                    % Menghitung grayLevel dari setiap pixel
            		ref_counts(grayLevel+ 1) = ref_counts(grayLevel+1) + 1;
            	end
            end
            % Menampilkan histogram dalam bentuk plot
            grayLevels = 0 : 255;
            bar(grayLevels, ref_counts, 'BarWidth', 1, 'FaceColor', 'b');
            xlabel('Gray Level', 'FontSize', 12);
            ylabel('Pixel Count', 'FontSize', 20);
            title('Gambar Referensi', 'FontSize', 12);
            grid on;
            M = zeros(256,1,'uint8');
            cdf1 = cumsum(ori_counts) / numel(ori);
            cdf2 = cumsum(ref_counts) / numel(ref);
            for idx = 1 : 256
                [~,ind] = min(abs(cdf1(idx) - cdf2));
                M(idx) = ind-1;
            end
            res = M(double(ori)+1);
            subplot(3,2,5)
            imshow(res)
            subplot(3,2,6)
            [rows, columns, ~] = size(res);
            res_counts = zeros(1, 256);
            for col = 1 : columns
            	for row = 1 : rows
            		grayLevel = res(row, col);
                    % Menghitung grayLevel dari setiap pixel
            		res_counts(grayLevel+ 1) = res_counts(grayLevel+1) + 1;
            	end
            end
            % Menampilkan histogram dalam bentuk plot
            grayLevels = 0 : 255;
            bar(grayLevels, res_counts, 'BarWidth', 1, 'FaceColor', 'b');
            xlabel('Gray Level', 'FontSize', 12);
            ylabel('Pixel Count', 'FontSize', 20);
            title('Setelah Specification', 'FontSize', 12);
            grid on;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Tugas1Citra = uifigure('Visible', 'off');
            app.Tugas1Citra.Name = 'Patients Display';
            app.Tugas1Citra.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Tugas1Citra);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PilihCitraLabel = uilabel(app.LeftPanel);
            app.PilihCitraLabel.Text = 'Pilih Citra';
            app.ImageBrighteningButton = uibutton(app.LeftPanel, 'push');
            app.ImageBrighteningButton.ButtonPushedFcn = createCallbackFcn(app, @ImageBrighteningButtonPushed, true);
            app.ImageBrighteningButton.Text = {'Image'; 'Brightening'};
            app.TransformasiLogButton = uibutton(app.LeftPanel, 'push');
            app.TransformasiLogButton.ButtonPushedFcn = createCallbackFcn(app, @TransformasiLogButtonPushed, true);
            app.TransformasiLogButton.Text = {'Transformasi'; 'Log'};
            app.TransformasiPangkatButton = uibutton(app.LeftPanel, 'push');
            app.TransformasiPangkatButton.ButtonPushedFcn = createCallbackFcn(app, @TransformasiPangkatButtonPushed, true);
            app.TransformasiPangkatButton.Text = {'Transformasi'; 'Pangkat'};
            app.PereganganHistogramButton = uibutton(app.LeftPanel, 'push');
            app.PereganganHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @PereganganHistogramButtonPushed, true);
            app.PereganganHistogramButton.Text = {'Peregangan'; 'Histogram'};
            app.HistogramEqualizationButton = uibutton(app.LeftPanel, 'push');
            app.HistogramEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizationButtonPushed, true);
            app.HistogramEqualizationButton.Text = {'Histogram'; 'Equalization'};
            app.HistogramSpecificationButton = uibutton(app.LeftPanel, 'push');
            app.HistogramSpecificationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramSpecificationButtonPushed, true);
            app.HistogramSpecificationButton.Text = {'Histogram'; 'Specification'};
            app.HitungHistogramButton = uibutton(app.LeftPanel, 'push');
            app.HitungHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HitungHistogramButtonPushed, true);
            app.HitungHistogramButton.Text = {'Hitung'; 'Histogram'};
            app.CariButton = uibutton(app.LeftPanel, 'push');
            app.CariButton.ButtonPushedFcn = createCallbackFcn(app, @CariButtonPushed, true);
            app.CariButton.Text = 'Cari';
            app.OperasiLabel = uilabel(app.LeftPanel);
            app.OperasiLabel.Text = 'Operasi';
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Text = 'a';
            app.ib_a = uieditfield(app.LeftPanel, 'numeric');
            app.bEditFieldLabel = uilabel(app.LeftPanel);
            app.bEditFieldLabel.Text = 'b';
            app.ib_b = uieditfield(app.LeftPanel, 'numeric');
            app.cEditFieldLabel = uilabel(app.LeftPanel);
            app.cEditFieldLabel.Text = 'c';
            app.tl_c = uieditfield(app.LeftPanel, 'numeric');
            app.cEditField_2Label = uilabel(app.LeftPanel);
            app.cEditField_2Label.Text = 'c';
            app.tp_c = uieditfield(app.LeftPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.LeftPanel);
            app.yEditFieldLabel.Text = 'y';
            app.tp_y = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.PreviewCitraLabel = uilabel(app.RightPanel);
            app.PreviewCitraLabel.Text = 'Preview Citra';
            % Show the figure after all components are created
            app.Tugas1Citra.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Tugas1Citra)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Tugas1Citra)
        end
    end
end